classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        Image                        matlab.ui.control.Image
        QuadrotorSizingUsignClustingTechniqueForDesginTendsLabel_3  matlab.ui.control.Label
        QuadrotorSizingUsignClustingTechniqueForDesginTendsLabel_2  matlab.ui.control.Label
        QuadrotorSizingUsignClustingTechniqueForDesginTendsLabel  matlab.ui.control.Label
        SizingResultatBasedonQuadrotorWeightTextArea  matlab.ui.control.TextArea
        SizingResultatBasedonQuadrotorWeightTextAreaLabel  matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        DesiredWeighttoThrustRatio2Slider  matlab.ui.control.Slider
        DesiredWeighttoThrustRatio2SliderLabel  matlab.ui.control.Label
        MessageTextArea              matlab.ui.control.TextArea
        MessageTextAreaLabel         matlab.ui.control.Label
        TargetWieghtgEditField       matlab.ui.control.NumericEditField
        TargetWieghtgEditFieldLabel  matlab.ui.control.Label
        ORLabel                      matlab.ui.control.Label
        SizingResultatBasedonQuadrotorWeightTextArea_2  matlab.ui.control.TextArea
        SizingResultatBasedonQuadrotorWeightTextArea_2Label  matlab.ui.control.Label
        QuadrotorLengthmmEditField   matlab.ui.control.NumericEditField
        QuadrotorLengthmmEditFieldLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        MotorCharacteristicsTab      matlab.ui.container.Tab
        PropellerCharacteristicsTab  matlab.ui.container.Tab
        BatteriesCharacteristicsTab  matlab.ui.container.Tab
        OtherTab                     matlab.ui.container.Tab
        Tab                          matlab.ui.container.Tab
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: QuadrotorLengthmmEditField
        function QuadrotorLengthmmEditFieldValueChanged(app, event)
 
           % quadrotorDiameter = app.QuadrotorLengthmmEditField.Value;
           % targetWeight = app.TargetWeight.Value
            % Function to calculate the six outputs
   
        % Get the quadrotor diameter from the input field
        quadrotorDiameter = app.QuadrotorLengthmmEditField.Value;
        targetWeight =app.TargetWieghtgEditField.Value;
       
        isTargetWeightFilled = (targetWeight > 0);
        isQuadrotorLengthFilled = (quadrotorDiameter > 0);
       

%        if (quadrotorDiameter > 0) && (targetWeight > 0)
%             app.MessageTextArea.Value = 'Please fill only one input quadrotor Length or Target Weight with values greater than zero.';
%             %return; % Exit the function
%         end

%         % Check if both inputs are empty
%         if isempty(quadrotorDiameter) && isempty(targetWeight)
%             app.MessageTextArea.Value = 'Please fill in either Quadrotor Length or Target Weight.';
%             return; % Exit the function
%         end

                if (isQuadrotorLengthFilled && ~isTargetWeightFilled)||(isQuadrotorLengthFilled && isTargetWeightFilled)
 
        Motor_Diameter = 1.52*realpow(quadrotorDiameter,0.46); % 
        Motor_height = 0.11*realpow(quadrotorDiameter,0.76);
        Motor_Weight = nthroot((Motor_Diameter/7.09),0.4); %  
        Motor_thrust =  Motor_Weight/0.032;   %  
        Motor_KV = 15.35*1000*realpow(Motor_Weight,-0.8); % Example equation
        Quadrotor_Length = quadrotorDiameter * 70; % Example equation

 % Convert the results to a formatted string
  outputText = sprintf('Motor Diameter (mm): %.2f\nMotor height(mm): %.2f\nMotor_Weight(g): %.2f\nMotor_thrust(g): %.2f\nMotor_KV(kv): %.2f\nQuadrotor_Length(mm): %.2f', ...
            Motor_Diameter, Motor_height, Motor_Weight, ...
            Motor_thrust, Motor_KV, quadrotorDiameter);
 
                elseif (isTargetWeightFilled) %&& ~isQuadrotorLengthFilled
 
%           Motor_thrust =  targetWeight*sliderValue/4;
%          Motor_Diameter = 1.52*realpow(targetWeight,0.46); % 
%         Motor_height = 0.11*realpow(targetWeight,0.76);
%         Motor_Weight = nthroot(targetWeight,7.09); %  
%         %Motor_thrust =  Motor_Weight/0.0032;   %  
%         Motor_KV = 15.35*1000*realpow(Motor_Weight,-0.8); % Example equation
%         Quadrotor_Length = NaN; % Example equation
%           
% 
%    outputText = sprintf('Motor_thrust (g): %.2f\nMotor height(mm): %.2f\nMotor_Weight(g): %.2f\nMotor_thrust(g): %.2f\nMotor_KV(kv): %.2f\nQuadrotor_Length(mm): %.2f', ...
%             Motor_thrust, Motor_height, Motor_Weight, ...
%             Motor_thrust, Motor_KV, quadrotorDiameter);


                end
        % Display the results in the Text Area
        app.SizingResultatBasedonQuadrotorWeightTextArea_2.Value = outputText;


        end

        % Callback function
        function ResetButtonValueChanged(app, event)
            value = app.ResetButton.Value;
             
             % You can add additional reset logic here if needed
        end

        % Value changed function: DesiredWeighttoThrustRatio2Slider
        function DesiredWeighttoThrustRatio2SliderValueChanged(app, event)
            sliderValue = app.DesiredWeighttoThrustRatio2Slider.Value;
                % Function to read the slider value
     
         % Display the value in a message box
        %msgbox(['Slider Value: ' num2str(sliderValue)], 'Slider Value');

        % Alternatively, you can update a text area or any other component
         %app.QuadrotorSizingUsignClustingTechniqueForDesginTendsLabel.Text = [num2str(sliderValue)];
     app.TextArea.Value = [num2str(sliderValue)];
        end

        % Value changed function: TargetWieghtgEditField
        function TargetWieghtgEditFieldValueChanged(app, event)
            targetWeight = app.TargetWieghtgEditField.Value;
             sliderValue = app.DesiredWeighttoThrustRatio2Slider.Value;
          Motor_thrust =  targetWeight*sliderValue/4;
          Motor_Diameter =  1.79*realpow(Motor_thrust,0.4); % 
            Motor_Weight = Motor_thrust*0.032; %  
          quadrotorDiameter=nthroot(Motor_Diameter/1.52,0.46);
          Motor_KV = 241*1000*realpow(Motor_thrust,-0.8);   
          Motor_height = 0.11*realpow(quadrotorDiameter,0.76);
        Quadrotor_Length = quadrotorDiameter;  
%           
% 
   outputText2 = sprintf(['Motor_Diameter(mm): %.2f\nMotor height(mm): %.2f\nMotor_Weight(g): ' ...
       '%.2f\nMotor_thrust(g): %.2f\nMotor_KV(kv): %.2f\nQuadrotor_Length(mm): %.2f'], ...
            Motor_Diameter, Motor_height, Motor_Weight, ...
            Motor_thrust, Motor_KV, quadrotorDiameter);

app.SizingResultatBasedonQuadrotorWeightTextArea.Value = outputText2;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MotorCharacteristicsTab = uitab(app.TabGroup);
            app.MotorCharacteristicsTab.Title = 'Motor Characteristics';
            app.PropellerCharacteristicsTab = uitab(app.TabGroup);
            app.PropellerCharacteristicsTab.Title = 'Propeller  Characteristics';
            app.BatteriesCharacteristicsTab = uitab(app.TabGroup);
            app.BatteriesCharacteristicsTab.Title = 'Batteries Characteristics';
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.QuadrotorLengthmmEditFieldLabel = uilabel(app.UIFigure);
            app.QuadrotorLengthmmEditFieldLabel.Text = 'Quadrotor Length  (mm)';
            app.QuadrotorLengthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.QuadrotorLengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @QuadrotorLengthmmEditFieldValueChanged, true);
            app.SizingResultatBasedonQuadrotorWeightTextArea_2Label = uilabel(app.UIFigure);
            app.SizingResultatBasedonQuadrotorWeightTextArea_2Label.Text = 'Sizing Resultat Based on Quadrotor Weight';
            app.SizingResultatBasedonQuadrotorWeightTextArea_2 = uitextarea(app.UIFigure);
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.TargetWieghtgEditFieldLabel = uilabel(app.UIFigure);
            app.TargetWieghtgEditFieldLabel.Text = 'Target Wieght (g) ';
            app.TargetWieghtgEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetWieghtgEditField.Limits = [0 Inf];
            app.TargetWieghtgEditField.ValueChangedFcn = createCallbackFcn(app, @TargetWieghtgEditFieldValueChanged, true);
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.MessageTextArea.Value = {'The cluster technique is a data-driven approach that assists in identifying the most suitable sizes for drone components based on a set of predefined design goals, constraints, and target features, leveraging statistical data from what are known as "design-trends" This entails analyzing existing flying configurations to identify common trends shared by many configurations.'};
            app.DesiredWeighttoThrustRatio2SliderLabel = uilabel(app.UIFigure);
            app.DesiredWeighttoThrustRatio2SliderLabel.Text = 'Desired Weight-to-Thrust Ratio  (=2)';
            app.DesiredWeighttoThrustRatio2Slider = uislider(app.UIFigure);
            app.DesiredWeighttoThrustRatio2Slider.Limits = [1 4];
            app.DesiredWeighttoThrustRatio2Slider.ValueChangedFcn = createCallbackFcn(app, @DesiredWeighttoThrustRatio2SliderValueChanged, true);
            app.DesiredWeighttoThrustRatio2Slider.Value = 2;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'2'};
            app.SizingResultatBasedonQuadrotorWeightTextAreaLabel = uilabel(app.UIFigure);
            app.SizingResultatBasedonQuadrotorWeightTextAreaLabel.Text = 'Sizing Resultat Based on Quadrotor Weight';
            app.SizingResultatBasedonQuadrotorWeightTextArea = uitextarea(app.UIFigure);
            app.QuadrotorSizingUsignClustingTechniqueForDesginTendsLabel = uilabel(app.UIFigure);
            app.QuadrotorSizingUsignClustingTechniqueForDesginTendsLabel.Text = 'Quadrotor Sizing Usign Clusting Technique For Statistical Desgin Trends';
            app.QuadrotorSizingUsignClustingTechniqueForDesginTendsLabel_2 = uilabel(app.UIFigure);
            app.QuadrotorSizingUsignClustingTechniqueForDesginTendsLabel_2.Text = 'The other design Trends UNDER CONSTRUCTION';
            app.QuadrotorSizingUsignClustingTechniqueForDesginTendsLabel_3 = uilabel(app.UIFigure);
            app.QuadrotorSizingUsignClustingTechniqueForDesginTendsLabel_3.Text = 'Mohamed khaider university of biskra T.K';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'my quadrotor.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end